classdef multiview_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        rad_connect_1           matlab.ui.control.Button
        rad_connect_2           matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        cam_port                matlab.ui.control.NumericEditField
        portEditFieldLabel_2    matlab.ui.control.Label
        cam_ip                  matlab.ui.control.EditField
        radar1ipLabel_2         matlab.ui.control.Label
        CollectButton           matlab.ui.control.Button
        portEditField_2Label    matlab.ui.control.Label
        portEditFieldLabel      matlab.ui.control.Label
        rad_reset_2             matlab.ui.control.Button
        rad_reset_1             matlab.ui.control.Button
        rad_stat_2              matlab.ui.control.Lamp
        statusLamp_2Label       matlab.ui.control.Label
        rad_stat_1              matlab.ui.control.Lamp
        statusLampLabel         matlab.ui.control.Label
        rad_ip_2                matlab.ui.control.EditField
        radar2ipEditFieldLabel  matlab.ui.control.Label
        rad_ip_1                matlab.ui.control.EditField
        rad_port_1              matlab.ui.control.NumericEditField
        rad_port_2              matlab.ui.control.NumericEditField
        radar1ipLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: rad_connect_1
        function rad_connect_1Pushed(app, event)
            connect(app.rad_ip_1.Value, app.rad_port_1.Value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            start(app.rad_ip_1.Value, app.rad_ip_2.Value, ...
                app.rad_port_1.Value, app.rad_port_2.Value, ...
                app.cam_ip.Value, app.cam_port.Value);
        end

        % Button pushed function: rad_connect_2
        function rad_connect_2ButtonPushed(app, event)
            connect(app.rad_ip_2.Value, app.rad_port_2.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.radar1ipLabel = uilabel(app.UIFigure);
            app.radar1ipLabel.Text = 'radar 1 (ip)';
            app.rad_port_2 = uieditfield(app.UIFigure, 'numeric');
            app.rad_port_1 = uieditfield(app.UIFigure, 'numeric');
            app.rad_ip_1 = uieditfield(app.UIFigure, 'text');
            app.radar2ipEditFieldLabel = uilabel(app.UIFigure);
            app.radar2ipEditFieldLabel.Text = 'radar 2 (ip)';
            app.rad_ip_2 = uieditfield(app.UIFigure, 'text');
            app.statusLampLabel = uilabel(app.UIFigure);
            app.statusLampLabel.Text = 'status';
            app.rad_stat_1 = uilamp(app.UIFigure);
            app.statusLamp_2Label = uilabel(app.UIFigure);
            app.statusLamp_2Label.Text = 'status';
            app.rad_stat_2 = uilamp(app.UIFigure);
            app.rad_reset_1 = uibutton(app.UIFigure, 'push');
            app.rad_reset_1.Text = 'reset';
            app.rad_reset_2 = uibutton(app.UIFigure, 'push');
            app.rad_reset_2.Text = 'reset';
            app.portEditFieldLabel = uilabel(app.UIFigure);
            app.portEditFieldLabel.Text = 'port';
            app.portEditField_2Label = uilabel(app.UIFigure);
            app.portEditField_2Label.Text = 'port';
            app.CollectButton = uibutton(app.UIFigure, 'push');
            app.CollectButton.Text = 'Collect';
            app.radar1ipLabel_2 = uilabel(app.UIFigure);
            app.radar1ipLabel_2.Text = 'camera (ip)';
            app.cam_ip = uieditfield(app.UIFigure, 'text');
            app.portEditFieldLabel_2 = uilabel(app.UIFigure);
            app.portEditFieldLabel_2.Text = 'port';
            app.cam_port = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.rad_connect_2 = uibutton(app.UIFigure, 'push');
            app.rad_connect_2.ButtonPushedFcn = createCallbackFcn(app, @rad_connect_2ButtonPushed, true);
            app.rad_connect_2.Text = 'connect';
            app.rad_connect_1 = uibutton(app.UIFigure, 'push');
            app.rad_connect_1.ButtonPushedFcn = createCallbackFcn(app, @rad_connect_1Pushed, true);
            app.rad_connect_1.Text = 'connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiview_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end